classdef air_purifier_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        AQITestSlider              matlab.ui.control.Slider
        AQITestSliderLabel         matlab.ui.control.Label
        SystemStateEditField       matlab.ui.control.NumericEditField
        SystemStateEditFieldLabel  matlab.ui.control.Label
        AirPurifierPrototypeLabel  matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        TimeElaspedEditField       matlab.ui.control.NumericEditField
        TimeElaspedEditFieldLabel  matlab.ui.control.Label
        aqi_display                matlab.ui.control.NumericEditField
        AQIEditFieldLabel          matlab.ui.control.Label
        FanModeButtonGroup         matlab.ui.container.ButtonGroup
        FANL4Button                matlab.ui.control.ToggleButton
        FANL3Button                matlab.ui.control.ToggleButton
        FANL2Button                matlab.ui.control.ToggleButton
        FANL1Button                matlab.ui.control.ToggleButton
        AUTOButton                 matlab.ui.control.ToggleButton
        OFFButton                  matlab.ui.control.ToggleButton
        PowerButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        systemState = 0; % Description
        state_off = 0;
        state_auto = 1;
        state_fanL1 = 2;
        state_fanL2 = 3;
        state_fanL3 = 4;
        state_fanL4 = 5;
        aqi_poll_timer % AQI Polling Timer
        aqi_reading = 0; % Description
        time_elapsed_seconds = 0; % Description
    end
    
    methods (Access = private)
        
        
        function systemModeUI(app, currentSystemMode)
            
            if (currentSystemMode == app.state_off)

                app.FANL4Button.Value = 0;
                app.OFFButton.Value = 1;

            elseif (currentSystemMode == app.state_auto)
                
                app.OFFButton.Value = 0;
                app.AUTOButton.Value = 1;

            elseif currentSystemMode == app.state_fanL1

                app.AUTOButton.Value = 0;
                app.FANL1Button.Value = 1;

             elseif currentSystemMode == app.state_fanL2

                app.FANL1Button.Value = 0;
                app.FANL2Button.Value = 1;

              elseif currentSystemMode == app.state_fanL3

                app.FANL2Button.Value = 0;
                app.FANL3Button.Value = 1;

               elseif currentSystemMode == app.state_fanL4

                app.FANL3Button.Value = 0;
                app.FANL4Button.Value = 1;

               elseif currentSystemMode == 6

                app.FANL4Button.Value = 0;
                app.OFFButton.Value = 1;
                app.systemState = 0;

            end


        end
        
        function ui_aqi_display(app)

            app.aqi_reading = app.AQITestSlider.Value;
            app.aqi_display.Value = app.aqi_reading;

            %update time elapsed
            app.time_elapsed_seconds = app.time_elapsed_seconds + 1;
            app.TimeElaspedEditField.Value = app.time_elapsed_seconds;
            
        end

        function ui_aqi_display_update(app, value)

            app.aqi_reading = value;
            app.aqi_display.Value = app.aqi_reading;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create timer object
            app.aqi_poll_timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.ui_aqi_display);      % Specify callback function

            
            start(app.aqi_poll_timer);


        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)

            % first increment SystemState
            % output to UI

                           %newSystemState = button_mode_logic(app.systemState);


            if app.systemState < 6

                app.systemState = app.systemState + 1;

                newSystemState = app.systemState;

            else
               
                app.systemState = 0;

                newSystemState = app.systemState;

            end

            systemModeUI(app, newSystemState);

            app.SystemStateEditField.Value = newSystemState;


        end

        % Callback function: AQITestSlider, AQITestSlider
        function AQITestSliderValueChanged(app, event)
            value = app.AQITestSlider.Value;
            
            app.ui_aqi_display_update(value);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {456, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AirPurifierPrototypeLabel = uilabel(app.LeftPanel);
            app.AirPurifierPrototypeLabel.Text = 'Air Purifier Prototype';
            app.SystemStateEditFieldLabel = uilabel(app.LeftPanel);
            app.SystemStateEditFieldLabel.Text = 'SystemState';
            app.SystemStateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AQITestSliderLabel = uilabel(app.LeftPanel);
            app.AQITestSliderLabel.Text = 'AQI Test';
            app.AQITestSlider = uislider(app.LeftPanel);
            app.AQITestSlider.Limits = [0 200];
            app.AQITestSlider.ValueChangedFcn = createCallbackFcn(app, @AQITestSliderValueChanged, true);
            app.AQITestSlider.ValueChangingFcn = createCallbackFcn(app, @AQITestSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.PowerButton = uibutton(app.RightPanel, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.FanModeButtonGroup = uibuttongroup(app.RightPanel);
            app.FanModeButtonGroup.Title = 'Fan Mode';
            app.OFFButton = uitogglebutton(app.FanModeButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.AUTOButton = uitogglebutton(app.FanModeButtonGroup);
            app.AUTOButton.Text = 'AUTO';
            app.FANL1Button = uitogglebutton(app.FanModeButtonGroup);
            app.FANL1Button.Text = 'FAN L1';
            app.FANL2Button = uitogglebutton(app.FanModeButtonGroup);
            app.FANL2Button.Text = 'FAN L2';
            app.FANL3Button = uitogglebutton(app.FanModeButtonGroup);
            app.FANL3Button.Text = 'FAN L3';
            app.FANL4Button = uitogglebutton(app.FanModeButtonGroup);
            app.FANL4Button.Text = 'FAN L4';
            app.AQIEditFieldLabel = uilabel(app.RightPanel);
            app.AQIEditFieldLabel.Text = 'AQI';
            app.aqi_display = uieditfield(app.RightPanel, 'numeric');
            app.TimeElaspedEditFieldLabel = uilabel(app.RightPanel);
            app.TimeElaspedEditFieldLabel.Text = 'Time Elasped';
            app.TimeElaspedEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = air_purifier_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end